classdef InterferogramSimulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        NotesLabel_2                   matlab.ui.control.Label
        PLAYButton                     matlab.ui.control.Button
        NotesLabel                     matlab.ui.control.Label
        AberrationSimulatorLabel       matlab.ui.control.Label
        copyrightLabel                 matlab.ui.control.Label
        loadinglabel                   matlab.ui.control.Label
        upperboundlabel                matlab.ui.control.Label
        lowerboundlabel                matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        constantaberrationslabel       matlab.ui.control.Label
        varyinglabel                   matlab.ui.control.Label
        ubedit                         matlab.ui.control.NumericEditField
        lbedit                         matlab.ui.control.NumericEditField
        WhichaberrationtovaryDropDown  matlab.ui.control.DropDown
        AberrationTypeDropDown         matlab.ui.control.DropDown
        WhichaberrationtypeLabel       matlab.ui.control.Label
        plottypeDropDown               matlab.ui.control.DropDown
        ViewLabel                      matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        frame % Frame saver for later replay
    end
   %% Useful Functions Which will be Called in Different Propties of the GUI
    methods (Access = private)
        % Calculating the Seidel Wavefront Error
        function SeidelW = SeidelCalc(app,d,varyindex,nframes,Rho,theta)
            if varyindex==0
                [W011x, W011y, W020, W220, W222, W131, W040, W311] = deal(d{:});
            else
                m = varygenerator(app,d,varyindex,nframes);
                [W011x, W011y, W020, W220, W222, W131, W040, W311] = deal(m{:});
            end
                tiltx = W011x .* Rho .* cos(theta); %equation for tiltx
                tilty = W011y .* Rho .* sin(theta); %equation for tilty
                defocus = W020 .* Rho.^2; %equation for defocus
                spherical = W220 .* Rho.^4; %equation for spherical
                astigmatism = W222 .* Rho.^2 .* (cos(theta).^2); %equation for astigmatism
                fieldcurvature = W131 .* Rho.^2; %equation for field curvature
                coma = W040 .* Rho.^3 .* cos(theta); %equation for coma
                distortion = W311 .* Rho .* cos(theta); %equation for distortion
                SeidelW = tiltx + tilty + defocus + spherical + astigmatism + fieldcurvature + coma + distortion; %sum of wavefront errors
        end

        % Calculating the Zernike Wavefront Error
        function ZernikeW = ZernikeCalc(app,d,varyindex,nframes,Rho,theta)
            if varyindex==0
                [Z1, Z2, Z3, Z4, Z5, Z6, Z7, Z8, Z9, Z10, Z11, Z12, Z13, Z14, Z15, Z16, Z17, Z18, Z19, Z20, Z21]...
                = deal(d{:});
            else
                m = varygenerator(app,d,varyindex,nframes);
                [Z1, Z2, Z3, Z4, Z5, Z6, Z7, Z8, Z9, Z10, Z11, Z12, Z13, Z14, Z15, Z16, Z17, Z18, Z19, Z20, Z21]...
                = deal(m{:});
            end

            % Defining the Phases!
            % First Degree Polynomials
            tiltx = Z1.*Rho.*cos(theta);
            tilty = Z2.*Rho.*sin(theta);
            Tilttotal = tiltx + tilty; % the phase aberration of tilt
            Defocus = Z3.*(Rho.^2 - 1); % the phase aberration of defocus
            deg_1 = Tilttotal + Defocus;
            % Second Degree Polynomials
            Astigx = Z4.*(Rho.^2.*(cos(2*theta))); % the phase aberration of Astigmatism
            Astigy = Z5.*(Rho.^2.*(sin(2*theta))); % the phase aberration of Astigmatism
            Comax = Z6.*((3.*Rho.^2 - 2).*Rho.*cos(theta));
            Comay = Z7.*((3.*Rho.^2 - 2).*Rho.*sin(theta));
            Spherical = Z8.*(6.*Rho.^4 - 6.*Rho.^2 +1); % the phase aberration of spherical
            deg_2 = Astigx + Astigy + Comax + Comay + Spherical; % The defined total phase for second degree
            % Third Degree Polynomials
            sad = Z9.*(Rho.^3.*cos(3.*theta));
            happy = Z10.*(Rho.^3.*sin(3.*theta));
            anxious = Z11.*((4.*Rho.^2 - 3).*Rho.^2.*cos(2*theta));
            nervous = Z12.*((4.*Rho.^2 - 3).*Rho.^2.*sin(2*theta));
            angry = Z13.*((10.*Rho.^4 - 12.*Rho.^2 + 3).*Rho.*cos(theta));
            excited = Z14.*((10.*Rho.^4 - 12.*Rho.^2 + 3).*Rho.*sin(theta));
            bored = Z15.*(20.*Rho.^6 - 30.*Rho.^4 + 12.*Rho.^2 - 1);
            deg_3 = sad + happy + anxious + nervous + angry + excited + bored;
            % Fourth Degree Polynomials
            UwU = Z16.*(Rho.^4 .*cos(4.*theta));
            OwO = Z17.*(Rho.^4 .*sin(4.*theta));
            AwA = Z18.*((5.*Rho.^2- 4).*Rho.^3.*cos(3.*theta));
            IwI = Z19.*((5.*Rho.^2- 4).*Rho.^3.*sin(3.*theta));
            YwY = Z20.*((15.*Rho.^4 - 20.*Rho.^2 + 6).*Rho.^2.*cos(2.*theta));
            MwM = Z21.*((15.*Rho.^4 - 20.*Rho.^2 + 6).*Rho.^2.*sin(2.*theta));
            deg_4 = UwU + OwO + AwA + IwI + YwY + MwM;
            % Total Phase!
            ZernikeW = deg_1 + deg_2 + deg_3 + deg_4;
        end

        % This function puts together the aberration values in a cell format
        % that can be then easily distributed to the aberration constant
        % variables for later calculation; this is specifically used for
        % the varying aberration, which needs an array of incrementing values 
        % defined by the user
        function m = varygenerator(app,d,varyindex,nframes)
            if app.lbedit.Value==app.ubedit.Value
                app.loadinglabel.FontSize = 17;
                app.loadinglabel.Text = "WARNING: UPPER BOUND=LOWER BOUND, STILL IMAGE PRODUCED";
                app.loadinglabel.BackgroundColor = [0.93,0.69,0.13];
                m = [d{1:varyindex-1} {app.lbedit.Value} d{varyindex:end}];
                pause(3)
            elseif app.lbedit.Value>app.ubedit.Value
                app.loadinglabel.FontSize = 17;
                app.loadinglabel.Text = "ERROR: UPPER BOUND NEEDS TO BE LARGER THAN LOWER BOUND";
                app.loadinglabel.BackgroundColor = [1.00,0.00,0.00];
                m = [d{1:varyindex-1} {0} d{varyindex:end}];
                drawnow
            else
                v = "linspace("+num2str(app.lbedit.Value)+","+num2str(app.ubedit.Value)+","+num2str(nframes)+");";
                v = eval(v);
                m = [d{1:varyindex-1} {v} d{varyindex:end}];
            end
        end

        % The function below clears the axes figure completely when called
        function clearboard(app)
            app.frame = 0;
            cla(app.UIAxes)
            ax = app.UIAxes;
            legend(ax, 'off');
            colorbar(ax, 'off');
            ax.Title.String = " ";
            ax.XLimitMethod = "tight";
            ax.YLimitMethod = "tight";
            ax.XTick = [];
            ax.YTick = [];
            ax.XLabel.String = " ";
            ax.YLabel.String = " ";
            app.loadinglabel.Text = " ";
            app.loadinglabel.BackgroundColor = "none";
            app.PLAYButton.Enable = "off";
        end
        
        % This function is essentially the "calculator and plotter". This does all the
        % main calculations for both the interferogram and the wave fans
        function refreshplot(app,d,varyindex,nframes)
            ax = app.UIAxes;
            legend(ax,"off");
            x.Title.String = app.plottypeDropDown.Value;
            x = linspace(-1.25,1.25,1000);
            y = linspace(-1.25,1.25,1000);
            app.PLAYButton.Enable = "off";
            [xx, yy] = meshgrid(x,y);
            [theta, Rho] = cart2pol(xx,yy); % Converting from cartesion (x,y) coordinates to polr (theta, Rho)
            circularpupil = find(Rho<=1); %limits pupil (p=<=1)
            Intensity = 0;
            Wavefront = 0;
            switch app.AberrationTypeDropDown.Value
                case 'Seidel (8)'
                    Wavefront = SeidelCalc(app,d,varyindex,nframes,Rho(circularpupil),theta(circularpupil));
                    Intensity = ((abs(1 + exp(1i .* Wavefront))).^2); %irradiance equation (Io = 1)
                case 'Zernike (21)'
                    Wavefront = ZernikeCalc(app,d,varyindex,nframes,Rho(circularpupil),theta(circularpupil));
                    Intensity = 1.*((abs(1+exp(1i.* Wavefront))).^2); % Complete Intensity Equation!
            end
            % switch case for the interferogram / wave fan plots
            switch app.plottypeDropDown.Value
                case 'Interferogram'
                    ax.XAxis.TickValues = [1 100 200 300 400 500 600 700 800 900 1000];
                    ax.YAxis.TickValues = [1 100 200 300 400 500 600 700 800 900 1000];
                    ax.XTickLabel = [-1.25 -1 -0.75 -0.5 -0.25 0 0.25 0.5 0.75 1 1.25];
                    ax.YTickLabel = [-1.25 -1 -0.75 -0.5 -0.25 0 0.25 0.5 0.75 1 1.25];
                    ax.XLabel.String = "\rho_x";
                    ax.YLabel.String = "\rho_y";
                    c = colorbar(app.UIAxes);
                    c.Label.String = "Intensity";
                    ax.XLimitMethod = "tight";
                    ax.YLimitMethod = "tight";
                    f = zeros([1000 1000 nframes]);
                    % Creating the animation of abrration variation OR
                    % still image of aberration constants
                    for i=1:nframes
                        oneI = zeros([1000 1000]);
                        oneI(circularpupil) = Intensity(:,i);
                        f(:,:,i) = oneI;
                        imagesc(ax, f(:,:,i));
                        ax.YDir = "normal";
                        drawnow
                    end
                    if nframes>1
                        app.PLAYButton.Enable = "on";
                        app.frame = f;
                    end
                case 'Wave Fan (Ray Intercept)'
                    zeroline = zeros(798);
                    grid(ax,"on");
                    ax.XAxis.TickValues = [1 100 200 300 400 500 600 700 799];
                    ax.XTickLabel = [-1 -0.75 -0.5 -0.25 0 0.25 0.5 0.75 1];
                    ax.YLimMode = "auto";
                    ax.YLim = [-inf, inf];
                    for i=1:nframes
                        oneW = zeros([1000 1000]);
                        oneW(circularpupil) = Wavefront(:,i);
                        rayy = -diff(oneW(500,:));
                        rayx = -diff(oneW(:,500));
                        plot(ax, rayx(101:899));
                        hold(ax,'on')
                        plot(ax, rayy(101:899),"LineStyle","--");
                        plot(ax, zeroline,"Color",'k');
                        legend(ax,"off");
                        legend(ax,"Wave Fan (\rho_x, 0)","Wave Fan (0, \rho_y)");
                        ax.XLabel.String = "\rho_x/\rho_y";
                        ax.YLabel.String = "[Arbitrary Units]";
                        hold(ax,'off')
                        drawnow
                    end
                end
            end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            % Initialize table with Seidel aberrations
            SeidelNames = ["W011x","W011y","W020","W220","W222","W131","W040","W311"];
            SeidelValues = zeros(1,length(SeidelNames));
            SeidelTable = table(SeidelNames',SeidelValues');
            set(app.UITable,"Data",SeidelTable);
            app.UITable.ColumnName = {'Abberation'; 'Constant Value'};
            app.UITable.ColumnWidth = {'fit'};
            app.UITable.RowName = {[1:length(SeidelNames)]};
            uisCenter = uistyle('HorizontalAlignment', 'center');
            addStyle(app.UITable, uisCenter)
            
            % Clear axes plot, make ready for figures
            ax = app.UIAxes;
            ax.Title.String = " ";
            ax.XLimitMethod = "tight";
            ax.YLimitMethod = "tight";
            ax.XTick = [];
            ax.YTick = [];
            ax.XLabel.String = " ";
            ax.YLabel.String = " ";
            
            % Initialize varying aberration drop down
            varyingdropdown = app.WhichaberrationtovaryDropDown;
            varyingdropdown.Items = ["none","W011x","W011y","W020","W220","W222","W131","W040","W311"];
            varyingdropdown.ItemsData = linspace(1,9,9);
        end

        % Value changed function: WhichaberrationtovaryDropDown
        function VaryingAbberationCallback(app, event)
            %The function below takes out the varying aberration from the list
            % in the spread sheet so that the user does not accientally input a
            % value for it
            % It also doesn't refresh the whole table so the user gets to
            % keep the other values they've already inputted for the other
            % aberrations
            app.ubedit.Value=0;
            app.lbedit.Value=0;
            index = app.WhichaberrationtovaryDropDown.Value;
            switch app.AberrationTypeDropDown.Value % Calculating the different varying aberrations for any aberration we choose to vary
                case 'Seidel (8)'
                    currentnames = app.UITable.Data.Var1';
                    SeidelNames = ["W011x","W011y","W020","W220","W222","W131","W040","W311"];
                    SeidelValues = app.UITable.Data.Var2';
                    if index~=1
                        [C i] = setdiff(SeidelNames,currentnames);
                        if ~isempty(i)
                            SeidelValues = [SeidelValues(1:i-1) 0 SeidelValues(i:end)];
                        end
                        SeidelNames(index-1) = [];
                        SeidelValues(index-1) = [];
                        SeidelTable = table(SeidelNames',SeidelValues');
                        set(app.UITable,"Data",SeidelTable);
                    else
                        [C i] = setdiff(SeidelNames,currentnames);
                        if ~isempty(i)
                            SeidelValues = [SeidelValues(1:i-1) 0 SeidelValues(i:end)];
                        end
                        SeidelTable = table(SeidelNames',SeidelValues');
                        set(app.UITable,"Data",SeidelTable);
                    end
                    app.UITable.RowName = {[1:length(SeidelNames)]};
                case 'Zernike (21)'
                    currentnames = app.UITable.Data.Var1';
                    ZernikeNames = ["Z1","Z2","Z3","Z4","Z5","Z6","Z7","Z8","Z9","Z10","Z11","Z12","Z13","Z14","Z15","Z16","Z17","Z18","Z19","Z20","Z21"];
                    if index~=1
                        [C i] = setdiff(ZernikeNames,currentnames);
                        ZernikeValues = app.UITable.Data.Var2';
                        if ~isempty(i)
                            ZernikeValues = [ZernikeValues(1:i-1) 0 ZernikeValues(i:end)];
                        end
                        ZernikeNames(index-1) = [];
                        ZernikeValues(index-1) = [];
                        SeidelTable = table(ZernikeNames',ZernikeValues');
                        set(app.UITable,"Data",SeidelTable);
                    else
                        [C i] = setdiff(ZernikeNames,currentnames);
                        if ~isempty(i)
                            ZernikeValues = [ZernikeValues(1:i-1) 0 ZernikeValues(i:end)];
                        end
                        SeidelTable = table(ZernikeNames',ZernikeValues');
                        set(app.UITable,"Data",SeidelTable);
                    end
                    app.UITable.RowName = {[1:length(ZernikeNames)]};
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            % Creating text that tells us the status of the calculations!
            app.loadinglabel.FontSize = 25;
            app.loadinglabel.Text = "LOADING FIGURE..."; 
            app.loadinglabel.BackgroundColor = [0.93,0.69,0.13];
            drawnow
            newData = event.NewData;
            nframes = 1;
            varyindex = 0;
            % tests 
            if isnan(newData)
                app.loadinglabel.FontSize = 17;
                app.loadinglabel.Text = "ERROR: PLEASE INPUT NUMERICAL VALUE"; % Says this if the user inputted non-numerical values!
                app.loadinglabel.BackgroundColor = [1.00,0.00,0.00];
                drawnow
            else
                data = app.UITable.Data;
                data.Var2 = fillmissing(data.Var2,'constant', 0);
                set(app.UITable,"Data",data);
                data = app.UITable.Data;
                d = num2cell(data.Var2);
                if app.WhichaberrationtovaryDropDown.Value~=1
                    if app.ubedit.Value~=app.lbedit.Value
                        nframes = 250;
                    end
                    varyindex = app.WhichaberrationtovaryDropDown.Value-1;
                end
                cla(app.UIAxes);
                drawnow
                refreshplot(app,d,varyindex,nframes);
                app.loadinglabel.Text = "FIGURE GENERATED"; % Says this when completed!
                app.loadinglabel.BackgroundColor = [0.00,1.00,0.00];
                pause(3)
                app.loadinglabel.Text = " ";
                app.loadinglabel.BackgroundColor = "none";
            end
        end

        % Value changed function: plottypeDropDown
        function plottypeDropDownValueChanged(app, event)
            % refresh plot to display new aberration
            clearboard(app);
            app.loadinglabel.FontSize = 25;
            app.loadinglabel.Text = "LOADING FIGURE..."; 
            app.loadinglabel.BackgroundColor = [0.93,0.69,0.13];
            nframes = 1;
            varyindex = 0;
            data = app.UITable.Data;
            data.Var2 = fillmissing(data.Var2,'constant', 0);
            set(app.UITable,"Data",data);
            data = app.UITable.Data;
            d = num2cell(data.Var2);
            if app.WhichaberrationtovaryDropDown.Value~=1
                if app.ubedit.Value~=app.lbedit.Value
                    nframes = 250;
                end
                varyindex = app.WhichaberrationtovaryDropDown.Value-1;
            end
            cla(app.UIAxes);
            drawnow
            refreshplot(app,d,varyindex,nframes);
            app.loadinglabel.Text = "FIGURE GENERATED"; % Says this when completed!
            app.loadinglabel.BackgroundColor = [0.00,1.00,0.00];
            pause(3)
            app.loadinglabel.Text = " ";
            app.loadinglabel.BackgroundColor = "none";
        end

        % Value changed function: AberrationTypeDropDown
        function AberrationTypeDropDownValueChanged(app, event)
            % This creates the drop down menu for which variable the user
            % wants to vary.
            clearboard(app);
            app.lbedit.Value = 0;
            app.ubedit.Value = 0;
            drawnow
            switch app.AberrationTypeDropDown.Value
                case 'Seidel (8)'
                    SeidelNames = ["W011x","W011y","W020","W220","W222","W131","W040","W311"];
                    SeidelValues = zeros(1,length(SeidelNames));
                    SeidelTable = table(SeidelNames',SeidelValues');
                    set(app.UITable,"Data",SeidelTable);
                    app.UITable.RowName = {[1:length(SeidelNames)]};
                    varyingdropdown = app.WhichaberrationtovaryDropDown;
                    varyingdropdown.Items = ["none","W011x","W011y","W020","W220","W222","W131","W040","W311"];
                    varyingdropdown.ItemsData = linspace(1,9,9);
                case 'Zernike (21)'
                    ZernikeNames = ["Z1","Z2","Z3","Z4","Z5","Z6","Z7","Z8","Z9","Z10","Z11","Z12","Z13","Z14","Z15","Z16","Z17","Z18","Z19","Z20","Z21"];
                    ZernikeValues = zeros(1,length(ZernikeNames));
                    ZernikeTable = table(ZernikeNames',ZernikeValues');
                    set(app.UITable,"Data",ZernikeTable);
                    app.UITable.RowName = {[1:length(ZernikeNames)]};
                    varyingdropdown = app.WhichaberrationtovaryDropDown;
                    varyingdropdown.Items = ["none","Z1","Z2","Z3","Z4","Z5","Z6","Z7","Z8","Z9","Z10","Z11","Z12","Z13","Z14","Z15","Z16","Z17","Z18","Z19","Z20","Z21"];
                    varyingdropdown.ItemsData = linspace(1,22,22);
            end
            app.WhichaberrationtovaryDropDown.Value = 1;
        end

        % Value changed function: ubedit
        function ubeditValueChanged(app, event)
            if app.WhichaberrationtovaryDropDown.Value==1
                app.loadinglabel.FontSize = 15;
                app.loadinglabel.Text = "WARNING: NO VARYING ABERRATION CHOSEN"; 
                app.loadinglabel.BackgroundColor = [0.93,0.69,0.13];
                pause(3)
            end
            app.loadinglabel.FontSize = 15;
            app.loadinglabel.Text = "WHEN FINISHED INPUTTING BOUNDS, EDIT VALUES IN TABLE TO GENERATE ANIMATION"; 
            app.loadinglabel.BackgroundColor = [0.93,0.69,0.13];
            pause(3)
            app.loadinglabel.Text = " "; 
            app.loadinglabel.BackgroundColor = "none";
        end

        % Value changed function: lbedit
        function lbeditValueChanged(app, event)
            if app.WhichaberrationtovaryDropDown.Value==1
                app.loadinglabel.FontSize = 25;
                app.loadinglabel.Text = "WARNING: NO VARYING ABERRATION CHOSEN"; 
                app.loadinglabel.BackgroundColor = [0.93,0.69,0.13];
                pause(3)
            end
            app.loadinglabel.FontSize = 15;
            app.loadinglabel.Text = "WHEN FINISHED INPUTTING BOUNDS, EDIT VALUES IN TABLE TO GENERATE ANIMATION"; 
            app.loadinglabel.BackgroundColor = [0.93,0.69,0.13];
            pause(3)
            app.loadinglabel.Text = " "; 
            app.loadinglabel.BackgroundColor = "none";
        end

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)
            app.loadinglabel.FontSize = 25;
            app.loadinglabel.Text = "REPLAYING ANIMATION..."; 
            app.loadinglabel.BackgroundColor = [0.93,0.69,0.13];
            drawnow
            % switch case for the interferogram / wave fan plots
            ax = app.UIAxes;
            ax.Title.String = app.plottypeDropDown.Value;
            nframes = size(app.frame);
            if length(nframes)==3
                nframes = nframes(3);
            else
                nframes=1;
            end
            % play button only works for Interferograms
            if app.plottypeDropDown.Value=='Interferogram'
                    ax.XAxis.TickValues = [1 100 200 300 400 500 600 700 800 900 1000];
                    ax.YAxis.TickValues = [1 100 200 300 400 500 600 700 800 900 1000];
                    ax.XTickLabel = [-1.25 -1 -0.75 -0.5 -0.25 0 0.25 0.5 0.75 1 1.25];
                    ax.YTickLabel = [-1.25 -1 -0.75 -0.5 -0.25 0 0.25 0.5 0.75 1 1.25];
                    ax.XLabel.String = "\rho_x";
                    ax.YLabel.String = "\rho_y";
                    c = colorbar(app.UIAxes);
                    c.Label.String = "Intensity";
                    ax.XLimitMethod = "tight";
                    ax.YLimitMethod = "tight";
                    % Creating the animation of abrration variation OR
                    % still image of aberration constants
                    for i=1:nframes
                        imagesc(ax, app.frame(:,:,i));
                        ax.YDir = "normal";
                        pause(0.05)
                    end
            end
            app.loadinglabel.Text = "REPLAY FINISHED"; % Says this when completed!
            app.loadinglabel.BackgroundColor = [0.00,1.00,0.00];
            pause(3)
            app.loadinglabel.Text = " ";
            app.loadinglabel.BackgroundColor = "none";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'gray')
            app.ViewLabel = uilabel(app.UIFigure);
            app.ViewLabel.Text = 'Plot Type';
            app.plottypeDropDown = uidropdown(app.UIFigure);
            app.plottypeDropDown.Items = {'Interferogram', 'Wave Fan (Ray Intercept)'};
            app.plottypeDropDown.ValueChangedFcn = createCallbackFcn(app, @plottypeDropDownValueChanged, true);
            app.plottypeDropDown.Value = 'Interferogram';
            app.WhichaberrationtypeLabel = uilabel(app.UIFigure);
            app.WhichaberrationtypeLabel.Text = 'Aberration Type';
            app.AberrationTypeDropDown = uidropdown(app.UIFigure);
            app.AberrationTypeDropDown.Items = {'Seidel (8)', 'Zernike (21)'};
            app.AberrationTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @AberrationTypeDropDownValueChanged, true);
            app.AberrationTypeDropDown.Value = 'Seidel (8)';
            app.WhichaberrationtovaryDropDown = uidropdown(app.UIFigure);
            app.WhichaberrationtovaryDropDown.Items = {'none', 'W011x', 'W011y', 'W020', 'W220', 'W222', 'W131', 'W040', 'W311'};
            app.WhichaberrationtovaryDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.WhichaberrationtovaryDropDown.ValueChangedFcn = createCallbackFcn(app, @VaryingAbberationCallback, true);
            app.WhichaberrationtovaryDropDown.Placeholder = 'Choose aberration to vary';
            app.WhichaberrationtovaryDropDown.Value = '1';
            app.lbedit = uieditfield(app.UIFigure, 'numeric');
            app.lbedit.ValueChangedFcn = createCallbackFcn(app, @lbeditValueChanged, true);
            app.ubedit = uieditfield(app.UIFigure, 'numeric');
            app.ubedit.ValueChangedFcn = createCallbackFcn(app, @ubeditValueChanged, true);
            app.varyinglabel = uilabel(app.UIFigure);
            app.varyinglabel.Text = 'Varying';
            app.constantaberrationslabel = uilabel(app.UIFigure);
            app.constantaberrationslabel.Text = 'Constant aberrations';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Aberration'; 'Constant Value [waves]'};
            app.UITable.ColumnSortable = [true false];
            app.UITable.ColumnEditable = [false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.lowerboundlabel = uilabel(app.UIFigure);
            app.lowerboundlabel.Text = 'Lower bound';
            app.upperboundlabel = uilabel(app.UIFigure);
            app.upperboundlabel.Text = 'Upper bound';
            app.loadinglabel = uilabel(app.UIFigure);
            app.loadinglabel.Text = ' ';
            app.copyrightLabel = uilabel(app.UIFigure);
            app.copyrightLabel.Enable = 'off';
            app.copyrightLabel.Visible = 'off';
            app.copyrightLabel.Text = 'GUI Created by Arabella M-S, Angelina Y, and Glory L for OPT212 on December 1st, 2022.  ';
            app.AberrationSimulatorLabel = uilabel(app.UIFigure);
            app.AberrationSimulatorLabel.Text = 'Aberration Simulator';
            app.NotesLabel = uilabel(app.UIFigure);
            app.NotesLabel.Text = 'Edit varying aberration before adding constant aberration. When a varying aberration is chosen with valid lower and upper bounds, an animation will run while it is generating. If you want to replay a generated inteferogram animation, please click the PLAY button that becomes enabled.';
            app.PLAYButton = uibutton(app.UIFigure, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Enable = 'off';
            app.PLAYButton.Text = 'PLAY';
            app.NotesLabel_2 = uilabel(app.UIFigure);
            app.NotesLabel_2.Text = 'NOTE: Animation for wave fans is supported, but very slow and hence not recommended (replay is also not supported). For best performance, please wait for program to finish loading a figure (generating/replaying) before starting another one -- live update works best for no varying aberration animation.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterferogramSimulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end